classdef Imaris_Collective_Migration_Plots_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SetupTab                       matlab.ui.container.Tab
        MATFilesListListBox            matlab.ui.control.ListBox
        MATFilesListListBoxLabel       matlab.ui.control.Label
        LoadButton_2                   matlab.ui.control.Button
        AvailableFilesDropDown_2       matlab.ui.control.DropDown
        AvailableFilesDropDown_2Label  matlab.ui.control.Label
        SetFilePathButton_2            matlab.ui.control.Button
        DisplacementPercentileJumpsEditField  matlab.ui.control.NumericEditField
        DisplacementPercentileJumpsEditFieldLabel  matlab.ui.control.Label
        TimeResolutionsEditField       matlab.ui.control.NumericEditField
        TimeResolutionsEditFieldLabel  matlab.ui.control.Label
        WindowSizeintimeintervalsEditField  matlab.ui.control.NumericEditField
        WindowSizeintimeintervalsEditFieldLabel  matlab.ui.control.Label
        SetParametersLabel             matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        SetFilePathButton              matlab.ui.control.Button
        AvailableFilesDropDown         matlab.ui.control.DropDown
        AvailableFilesDropDownLabel    matlab.ui.control.Label
        MigrationAnglesTab             matlab.ui.container.Tab
        TimesSlider                    matlab.ui.control.Slider
        TimesSliderLabel               matlab.ui.control.Label
        GroupCorrelationTab            matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        DisplacementCorrelationTab     matlab.ui.container.Tab
        ResetButton_2                  matlab.ui.control.Button
        DisplacementplotrangeLabel     matlab.ui.control.Label
        MaxEditField                   matlab.ui.control.NumericEditField
        MaxEditFieldLabel              matlab.ui.control.Label
        MinEditField                   matlab.ui.control.NumericEditField
        MinEditFieldLabel              matlab.ui.control.Label
        TimesSlider_2                  matlab.ui.control.Slider
        TimesSlider_2Label             matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        DisplacementCorrelationinatimeframeTab  matlab.ui.container.Tab
        ResetButton                    matlab.ui.control.Button
        MaxEditField_2                 matlab.ui.control.NumericEditField
        MaxEditField_2Label            matlab.ui.control.Label
        MinEditField_2                 matlab.ui.control.NumericEditField
        MinEditField_2Label            matlab.ui.control.Label
        DisplacementplotrangeLabel_2   matlab.ui.control.Label
        TimeLimitssSlider_2            matlab.ui.control.Slider
        TimeLimitssSlider_2Label       matlab.ui.control.Label
        TimeLimitssSlider              matlab.ui.control.Slider
        TimeLimitssSliderLabel         matlab.ui.control.Label
        UIAxes3_2                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        FolderPath % Description
        FileName

        pax

        timeframe
        importdata
        correlationdata
        corrval
        disp_correlations_plotdata
        distancecorrelationdata
        total_dist_plotdata
        
        disp_x_min
        disp_x_max
        
    end
    
    methods (Access = public)
        
        function TimeFrame_Displacement_Plot(app)
            
            Minimum = min(app.TimeLimitssSlider.Value,app.TimeLimitssSlider_2.Value);
            Maximum = max(app.TimeLimitssSlider.Value,app.TimeLimitssSlider_2.Value);
            Condition = app.disp_correlations_plotdata.Time>=Minimum & app.disp_correlations_plotdata.Time<=Maximum;
            
            xdat = reshape(cell2mat(app.disp_correlations_plotdata.dist_intervals(Condition)),[],1);
            ydat = reshape(cell2mat(app.disp_correlations_plotdata.dist_correlations(Condition)),[],1);
            
            scatter(app.UIAxes3_2,xdat,abs(ydat),'x')
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pax = polaraxes(app.MigrationAnglesTab);
            app.pax.Units = 'pixels';
            app.pax.Position = [200 80 430 430];
        end

        % Button pushed function: SetFilePathButton
        function SetFilePathButtonPushed(app, event)
            [~,app.FolderPath] = uigetfile('*.csv','Please select any .csv file within the folder of your data');
            figure(app.UIFigure)

            FileList = dir([app.FolderPath,'*.csv']);

            filenumber = numel(FileList);

            filenames = {};
            for i = 1:filenumber
                filenames{i} = FileList(i).name;
            end

            app.AvailableFilesDropDown.Items = filenames;


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            f = waitbar(0,'Please wait...');
            pause(0.5)

            app.FileName = [app.FolderPath,app.AvailableFilesDropDown.Value];
            waitbar(.4,f,'Loading and Processing your data...');
            [app.timeframe,app.importdata,...
                app.correlationdata,app.corrval,...
                app.distancecorrelationdata,app.disp_correlations_plotdata,app.total_dist_plotdata]...
                = datasplitapp_keng(app.FileName,app.WindowSizeintimeintervalsEditField.Value,app.TimeResolutionsEditField.Value,...
                app.DisplacementPercentileJumpsEditField.Value);

            waitbar(.8,f,'Plotting...');

            % Set Sliders
            SliderLimits = [app.timeframe(app.WindowSizeintimeintervalsEditField.Value), max(app.timeframe)];
            SliderTicks = [app.timeframe(app.WindowSizeintimeintervalsEditField.Value):app.TimeResolutionsEditField.Value:max(app.timeframe)];

            app.TimesSlider.Limits = SliderLimits;
            app.TimesSlider_2.Limits = SliderLimits;
            app.TimeLimitssSlider.Limits = SliderLimits;
            app.TimeLimitssSlider_2.Limits = SliderLimits;

            app.TimesSlider.MinorTicks = SliderTicks;
            app.TimesSlider_2.MinorTicks = SliderTicks;
            app.TimeLimitssSlider.MinorTicks = SliderTicks;
            app.TimeLimitssSlider_2.MinorTicks = SliderTicks;

            % Do Group Correlation Plots
            plot(app.UIAxes,app.timeframe./60,app.corrval) %in minutes
            plot(app.UIAxes2,app.timeframe./60,abs(app.corrval)) %in minutes

            grid(app.UIAxes,'on')
            grid(app.UIAxes2,'on')
            grid(app.UIAxes3,'on')
            grid(app.UIAxes3_2,'on')
            
            ylim(app.UIAxes,[-1,1])
            ylim(app.UIAxes2,[0,1])
            ylim(app.UIAxes3,[0,1])
            ylim(app.UIAxes3_2,[0,1])
            
            waitbar(1,f,'Finishing...');
            pause(0.5)
            close(f)

        end

        % Value changed function: TimesSlider
        function TimesSliderValueChanged(app, event)
            app.TimesSlider.Value = app.TimeResolutionsEditField.Value*floor(app.TimesSlider.Value/app.TimeResolutionsEditField.Value);

            polarhistogram(app.pax,deg2rad(app.importdata.Angle2D_windowpossec(app.importdata.Time==app.TimesSlider.Value)))
            
        end

        % Value changed function: TimesSlider_2
        function TimesSlider_2ValueChanged(app, event)
            app.TimesSlider_2.Value = app.TimeResolutionsEditField.Value*floor(app.TimesSlider_2.Value/app.TimeResolutionsEditField.Value);
            
            plot(app.UIAxes3,app.disp_correlations_plotdata.dist_intervals{app.disp_correlations_plotdata.Time==app.TimesSlider_2.Value},...
                app.disp_correlations_plotdata.dist_correlations{app.disp_correlations_plotdata.Time==app.TimesSlider_2.Value})
            
            
        end

        % Value changed function: TimeLimitssSlider
        function TimeLimitssSliderValueChanged(app, event)
            app.TimeLimitssSlider.Value = app.TimeResolutionsEditField.Value*floor(app.TimeLimitssSlider.Value/app.TimeResolutionsEditField.Value);
            TimeFrame_Displacement_Plot(app)
        end

        % Value changed function: TimeLimitssSlider_2
        function TimeLimitssSlider_2ValueChanged(app, event)
            app.TimeLimitssSlider_2.Value = app.TimeResolutionsEditField.Value*floor(app.TimeLimitssSlider_2.Value/app.TimeResolutionsEditField.Value);
            TimeFrame_Displacement_Plot(app)
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            xlim(app.UIAxes3,[app.MinEditField.Value,app.MaxEditField.Value]);
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            xlim(app.UIAxes3,[app.MinEditField.Value,app.MaxEditField.Value]);
        end

        % Value changed function: MinEditField_2
        function MinEditField_2ValueChanged(app, event)
            xlim(app.UIAxes3_2,[app.MinEditField_2.Value,app.MaxEditField_2.Value]);
        end

        % Value changed function: MaxEditField_2
        function MaxEditField_2ValueChanged(app, event)
            xlim(app.UIAxes3_2,[app.MinEditField_2.Value,app.MaxEditField_2.Value]);
        end

        % Value changing function: TimesSlider_2
        function TimesSlider_2ValueChanging(app, event)
            changingValue = app.TimeResolutionsEditField.Value*floor(event.Value/app.TimeResolutionsEditField.Value);
            
            plot(app.UIAxes3,app.disp_correlations_plotdata.dist_intervals{app.disp_correlations_plotdata.Time==changingValue},...
                app.disp_correlations_plotdata.dist_correlations{app.disp_correlations_plotdata.Time==changingValue})
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             xlim(app.UIAxes3_2,'auto')
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            xlim(app.UIAxes3,'auto')
        end

        % Button pushed function: SetFilePathButton_2
        function SetFilePathButton_2Pushed(app, event)
            [~,app.FolderPath] = uigetfile('*.mat','Please select any .mat file within the folder of your data');
            figure(app.UIFigure)

            FileList = dir([app.FolderPath,'*.mat']);

            filenumber = numel(FileList);

            filenames = {};
            for i = 1:filenumber
                filenames{i} = FileList(i).name;
            end

%             app.AvailableFilesDropDown_2.Items = filenames;
            app.MATFilesListListBox.Items = filenames;
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            f = waitbar(0,'Please wait...');
            pause(0.5)

%             app.FileName = [app.FolderPath,app.AvailableFilesDropDown_2.Value];
            app.FileName = [app.FolderPath,app.MATFilesListListBox.Value];


            waitbar(.4,f,'Loading in your data...');
            
            load(app.FileName,"timeframe","importdata",...
                "correlationdata","corrval",...
                "distancecorrelationdata","disp_correlations_plotdata","total_dist_plotdata")
            
            app.timeframe = timeframe;
            app.importdata = importdata;
            app.correlationdata = correlationdata;
            app.corrval = corrval;
            app.disp_correlations_plotdata = disp_correlations_plotdata;
            app.distancecorrelationdata = distancecorrelationdata;
            app.total_dist_plotdata = total_dist_plotdata;

            waitbar(.8,f,'Plotting...');

            % Set Sliders
            SliderLimits = [app.timeframe(app.WindowSizeintimeintervalsEditField.Value), max(app.timeframe)];
            SliderTicks = [app.timeframe(app.WindowSizeintimeintervalsEditField.Value):app.TimeResolutionsEditField.Value:max(app.timeframe)];

            app.TimesSlider.Limits = SliderLimits;
            app.TimesSlider_2.Limits = SliderLimits;
            app.TimeLimitssSlider.Limits = SliderLimits;
            app.TimeLimitssSlider_2.Limits = SliderLimits;

            app.TimesSlider.MinorTicks = SliderTicks;
            app.TimesSlider_2.MinorTicks = SliderTicks;
            app.TimeLimitssSlider.MinorTicks = SliderTicks;
            app.TimeLimitssSlider_2.MinorTicks = SliderTicks;

            % Do Group Correlation Plots
            plot(app.UIAxes,app.timeframe./60,app.corrval) %in minutes
            plot(app.UIAxes2,app.timeframe./60,abs(app.corrval)) %in minutes

            grid(app.UIAxes,'on')
            grid(app.UIAxes2,'on')
            grid(app.UIAxes3,'on')
            grid(app.UIAxes3_2,'on')

            ylim(app.UIAxes,[-1,1])
            ylim(app.UIAxes2,[0,1])
            ylim(app.UIAxes3,[0,1])
            ylim(app.UIAxes3_2,[0,1])

            waitbar(1,f,'Finishing...');
            pause(0.5)
            close(f)
        end

        % Value changing function: TimeLimitssSlider
        function TimeLimitssSliderValueChanging(app, event)
            changingValue = app.TimeResolutionsEditField.Value*floor(event.Value/app.TimeResolutionsEditField.Value);
            
            Minimum = min(changingValue,app.TimeLimitssSlider_2.Value);
            Maximum = max(changingValue,app.TimeLimitssSlider_2.Value);
            Condition = app.disp_correlations_plotdata.Time>=Minimum & app.disp_correlations_plotdata.Time<=Maximum;
            
            xdat = reshape(cell2mat(app.disp_correlations_plotdata.dist_intervals(Condition)),[],1);
            ydat = reshape(cell2mat(app.disp_correlations_plotdata.dist_correlations(Condition)),[],1);
            
            scatter(app.UIAxes3_2,xdat,abs(ydat),'x')
        end

        % Value changing function: TimeLimitssSlider_2
        function TimeLimitssSlider_2ValueChanging(app, event)
            changingValue = app.TimeResolutionsEditField.Value*floor(event.Value/app.TimeResolutionsEditField.Value);
            
            Minimum = min(changingValue,app.TimeLimitssSlider.Value);
            Maximum = max(changingValue,app.TimeLimitssSlider.Value);
            Condition = app.disp_correlations_plotdata.Time>=Minimum & app.disp_correlations_plotdata.Time<=Maximum;
            
            xdat = reshape(cell2mat(app.disp_correlations_plotdata.dist_intervals(Condition)),[],1);
            ydat = reshape(cell2mat(app.disp_correlations_plotdata.dist_correlations(Condition)),[],1);
            
            scatter(app.UIAxes3_2,xdat,abs(ydat),'x')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.AvailableFilesDropDownLabel = uilabel(app.SetupTab);
            app.AvailableFilesDropDownLabel.Text = 'Available Files';
            app.AvailableFilesDropDown = uidropdown(app.SetupTab);
            app.AvailableFilesDropDown.Items = {};
            app.AvailableFilesDropDown.Value = {};
            app.SetFilePathButton = uibutton(app.SetupTab, 'push');
            app.SetFilePathButton.ButtonPushedFcn = createCallbackFcn(app, @SetFilePathButtonPushed, true);
            app.SetFilePathButton.Text = 'Set File Path';
            app.LoadButton = uibutton(app.SetupTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SetParametersLabel = uilabel(app.SetupTab);
            app.SetParametersLabel.Text = 'Set Parameters:';
            app.WindowSizeintimeintervalsEditFieldLabel = uilabel(app.SetupTab);
            app.WindowSizeintimeintervalsEditFieldLabel.Text = 'Window Size (in time intervals)';
            app.WindowSizeintimeintervalsEditField = uieditfield(app.SetupTab, 'numeric');
            app.WindowSizeintimeintervalsEditField.Value = 5;
            app.TimeResolutionsEditFieldLabel = uilabel(app.SetupTab);
            app.TimeResolutionsEditFieldLabel.Text = 'Time Resolution (s)';
            app.TimeResolutionsEditField = uieditfield(app.SetupTab, 'numeric');
            app.TimeResolutionsEditField.Value = 40;
            app.DisplacementPercentileJumpsEditFieldLabel = uilabel(app.SetupTab);
            app.DisplacementPercentileJumpsEditFieldLabel.Text = 'Displacement Percentile Jumps';
            app.DisplacementPercentileJumpsEditField = uieditfield(app.SetupTab, 'numeric');
            app.DisplacementPercentileJumpsEditField.Value = 5;
            app.SetFilePathButton_2 = uibutton(app.SetupTab, 'push');
            app.SetFilePathButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetFilePathButton_2Pushed, true);
            app.SetFilePathButton_2.Text = 'Set File Path';
            app.AvailableFilesDropDown_2Label = uilabel(app.SetupTab);
            app.AvailableFilesDropDown_2Label.Text = 'Available Files';
            app.AvailableFilesDropDown_2 = uidropdown(app.SetupTab);
            app.AvailableFilesDropDown_2.Items = {};
            app.AvailableFilesDropDown_2.Value = {};
            app.LoadButton_2 = uibutton(app.SetupTab, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.MATFilesListListBoxLabel = uilabel(app.SetupTab);
            app.MATFilesListListBoxLabel.Text = 'MAT Files List';
            app.MATFilesListListBox = uilistbox(app.SetupTab);
            app.MATFilesListListBox.Items = {};
            app.MATFilesListListBox.Value = {};
            app.MigrationAnglesTab = uitab(app.TabGroup);
            app.MigrationAnglesTab.Title = 'Migration Angles';
            app.TimesSliderLabel = uilabel(app.MigrationAnglesTab);
            app.TimesSliderLabel.Text = 'Time (s)';
            app.TimesSlider = uislider(app.MigrationAnglesTab);
            app.TimesSlider.ValueChangedFcn = createCallbackFcn(app, @TimesSliderValueChanged, true);
            app.GroupCorrelationTab = uitab(app.TabGroup);
            app.GroupCorrelationTab.Title = 'Group Correlation';
            app.UIAxes = uiaxes(app.GroupCorrelationTab);
            title(app.UIAxes, 'Group Correlation')
            xlabel(app.UIAxes, 'Time (mins)')
            ylabel(app.UIAxes, 'Correlation')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GroupCorrelationTab);
            title(app.UIAxes2, '''Order Parameter'' Correlation')
            xlabel(app.UIAxes2, 'Time (mins)')
            ylabel(app.UIAxes2, 'Correlation')
            zlabel(app.UIAxes2, 'Z')
            app.DisplacementCorrelationTab = uitab(app.TabGroup);
            app.DisplacementCorrelationTab.Title = 'Displacement Correlation';
            app.UIAxes3 = uiaxes(app.DisplacementCorrelationTab);
            xlabel(app.UIAxes3, 'Displacement')
            ylabel(app.UIAxes3, 'Correlation')
            zlabel(app.UIAxes3, 'Z')
            app.TimesSlider_2Label = uilabel(app.DisplacementCorrelationTab);
            app.TimesSlider_2Label.Text = 'Time (s)';
            app.TimesSlider_2 = uislider(app.DisplacementCorrelationTab);
            app.TimesSlider_2.ValueChangedFcn = createCallbackFcn(app, @TimesSlider_2ValueChanged, true);
            app.TimesSlider_2.ValueChangingFcn = createCallbackFcn(app, @TimesSlider_2ValueChanging, true);
            app.MinEditFieldLabel = uilabel(app.DisplacementCorrelationTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.DisplacementCorrelationTab, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.DisplacementCorrelationTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.DisplacementCorrelationTab, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Value = 20;
            app.DisplacementplotrangeLabel = uilabel(app.DisplacementCorrelationTab);
            app.DisplacementplotrangeLabel.Text = 'Displacement plot range';
            app.ResetButton_2 = uibutton(app.DisplacementCorrelationTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.DisplacementCorrelationinatimeframeTab = uitab(app.TabGroup);
            app.DisplacementCorrelationinatimeframeTab.Title = 'Displacement Correlation in a timeframe';
            app.UIAxes3_2 = uiaxes(app.DisplacementCorrelationinatimeframeTab);
            xlabel(app.UIAxes3_2, 'Displacement')
            ylabel(app.UIAxes3_2, 'Correlation')
            zlabel(app.UIAxes3_2, 'Z')
            app.TimeLimitssSliderLabel = uilabel(app.DisplacementCorrelationinatimeframeTab);
            app.TimeLimitssSliderLabel.Text = 'Time Limits (s)';
            app.TimeLimitssSlider = uislider(app.DisplacementCorrelationinatimeframeTab);
            app.TimeLimitssSlider.ValueChangedFcn = createCallbackFcn(app, @TimeLimitssSliderValueChanged, true);
            app.TimeLimitssSlider.ValueChangingFcn = createCallbackFcn(app, @TimeLimitssSliderValueChanging, true);
            app.TimeLimitssSlider_2Label = uilabel(app.DisplacementCorrelationinatimeframeTab);
            app.TimeLimitssSlider_2Label.Text = 'Time Limits (s)';
            app.TimeLimitssSlider_2 = uislider(app.DisplacementCorrelationinatimeframeTab);
            app.TimeLimitssSlider_2.ValueChangedFcn = createCallbackFcn(app, @TimeLimitssSlider_2ValueChanged, true);
            app.TimeLimitssSlider_2.ValueChangingFcn = createCallbackFcn(app, @TimeLimitssSlider_2ValueChanging, true);
            app.DisplacementplotrangeLabel_2 = uilabel(app.DisplacementCorrelationinatimeframeTab);
            app.DisplacementplotrangeLabel_2.Text = 'Displacement plot range';
            app.MinEditField_2Label = uilabel(app.DisplacementCorrelationinatimeframeTab);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.DisplacementCorrelationinatimeframeTab, 'numeric');
            app.MinEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinEditField_2ValueChanged, true);
            app.MaxEditField_2Label = uilabel(app.DisplacementCorrelationinatimeframeTab);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.DisplacementCorrelationinatimeframeTab, 'numeric');
            app.MaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_2ValueChanged, true);
            app.MaxEditField_2.Value = 20;
            app.ResetButton = uibutton(app.DisplacementCorrelationinatimeframeTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Imaris_Collective_Migration_Plots_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end